classdef PORSAViz_v0p6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FalsecolourCheckBox            matlab.ui.control.CheckBox
        VelocityupperlimitmsEditField  matlab.ui.control.NumericEditField
        VelocityupperlimitmsEditFieldLabel  matlab.ui.control.Label
        ScaleEditField                 matlab.ui.control.NumericEditField
        ScaleEditFieldLabel            matlab.ui.control.Label
        ColourbarCheckBox              matlab.ui.control.CheckBox
        ZoomoutontimelineButton        matlab.ui.control.Button
        ZoomStopLamp                   matlab.ui.control.Lamp
        ZoomStartLamp                  matlab.ui.control.Lamp
        Slider_2                       matlab.ui.control.Slider
        ZoominontimelineButton         matlab.ui.control.Button
        VideofilenameEditField         matlab.ui.control.EditField
        VideofilenameEditFieldLabel    matlab.ui.control.Label
        ExportVideoClipButton          matlab.ui.control.Button
        StopLamp                       matlab.ui.control.Lamp
        StartLamp                      matlab.ui.control.Lamp
        ScaleSetLamp                   matlab.ui.control.Lamp
        ScaleSetLampLabel              matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        ColormapDropDown               matlab.ui.control.DropDown
        RulerButton                    matlab.ui.control.Button
        DistancemmEditField            matlab.ui.control.NumericEditField
        DistancemmEditFieldLabel       matlab.ui.control.Label
        SetscaleButton                 matlab.ui.control.Button
        EstimateVelocityCheckBox       matlab.ui.control.CheckBox
        ContrastenhanceCheckBox        matlab.ui.control.CheckBox
        fname_label                    matlab.ui.control.Label
        EndtimeminEditField            matlab.ui.control.NumericEditField
        EndtimeminEditFieldLabel       matlab.ui.control.Label
        OpenFileButton                 matlab.ui.control.Button
        SetEndButton                   matlab.ui.control.Button
        SetStartButton                 matlab.ui.control.Button
        Slider                         matlab.ui.control.Slider
        StarttimeminEditField          matlab.ui.control.NumericEditField
        StarttimeminEditFieldLabel     matlab.ui.control.Label
        PlayButton                     matlab.ui.control.Button
        ImageAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        fname           % filepath
        frame           % Current video frame
        vid_obj         % input video data
        imgsub          % Contrast enhanced image
        RULER           % Interactive ruler
        scale_factor    % scaling pixels per mm
        opticFlow       % Optical flow object
        flow            % Velocity data
    end
    
    methods (Access = private)
        
        function read_vid(app)
            try
                app.vid_obj = VideoReader(app.fname);
            catch ME
                % If problem reading video frame, display error message
                uialert(app.UIFigure, ME.message, 'Video playback Error');
                return;
            end
            
            app.StarttimeminEditField.Value = 0;
            app.EndtimeminEditField.Value = app.vid_obj.Duration/60;
            
            app.vid_obj.CurrentTime = 0;
            
            
            app.configure_slider
            app.Slider.Visible = 'on';
            app.SetEndButton.Visible = 'on';
            app.SetStartButton.Visible = 'on';
            app.ResetButton.Visible = 'on';
            app.PlayButton.Visible = 'on';
            app.ZoominontimelineButton.Visible = 'on';
            
            % Disable panning
            app.ImageAxes.Interactions = panInteraction;
            disableDefaultInteractivity(app.ImageAxes)
            
            % Enable Toolbar
            app.ImageAxes.Toolbar.Visible = 'off';
            
            [~,filename,~] = fileparts(app.fname);
            app.fname_label.Text = ['Current file: ',filename];
            
            % Optical flow setup
            app.opticFlow = opticalFlowFarneback('NumPyramidLevels',3,...
                                                 'NumIterations',3,...
                                                 'NeighborhoodSize',7,...
                                                'FilterSize',11);
                    
            app.updateframe

            % Returning focus to window so it doesn't go minimise
            figure(app.UIFigure)
            
        end
        
        function updateframe(app)
            try
                app.vid_obj.CurrentTime = app.Slider.Value*60;
                app.frame = readFrame(app.vid_obj);
            catch ME
                uialert(app.UIFigure, 'Please load a video file', 'No video file loaded');
            end
            
            % Compute contrast enhanced image
            img0 = readFrame(app.vid_obj);
            Mask = app.frame(:,:,3) > 5;

            app.imgsub = single(img0) - single(app.frame);
            app.imgsub = mean(app.imgsub,3);
            app.imgsub = (1./(1+exp(-0.1*app.imgsub)));
            app.imgsub = medfilt2(app.imgsub,[3,3]);
            
            hold(app.ImageAxes,'off')

            if app.FalsecolourCheckBox.Value == 0 && app.ContrastenhanceCheckBox.Value == 0
                imshow(app.frame,'Parent',app.ImageAxes);
            elseif app.FalsecolourCheckBox.Value == 1 && app.ContrastenhanceCheckBox.Value == 0
                imshow(mat2gray(mean(app.frame,3)),'Parent',app.ImageAxes);
                colormap(app.ImageAxes, app.ColormapDropDown.Value)
            elseif app.FalsecolourCheckBox.Value == 0 && app.ContrastenhanceCheckBox.Value == 1
                imshow(app.imgsub .* Mask,'Parent',app.ImageAxes);
            elseif app.FalsecolourCheckBox.Value == 1 && app.ContrastenhanceCheckBox.Value == 1
                imshow(app.imgsub .* Mask,'Parent',app.ImageAxes);
                colormap(app.ImageAxes, app.ColormapDropDown.Value)
            end

            % Check is optical flow plot is enabled by user
            switch app.EstimateVelocityCheckBox.Value
            case 0
                hold(app.ImageAxes,'off')
            case 1
                app.estimate_velocity()
                app.ColourbarCheckBox.Value = 1;
                colorbar( app.ImageAxes );
            end

            videoName = app.vid_obj.Name;
            videoName = videoName(1:end-4);
            Slim = app.Slider_2.Limits;
            videoName = [videoName,'_',num2str(Slim(1),'%4.2f'),'_',num2str(Slim(2),'%4.2f')];
            app.VideofilenameEditField.Value = videoName;
        end
        
        function estimate_velocity(app)
            % apply optical flow to current frame
            app.flow = estimateFlow(app.opticFlow,app.imgsub);
            Vmag = hypot(app.flow.Vx,app.flow.Vy);
            dt = 1/app.vid_obj.FrameRate;
            
            
            %Applying defined scale factor if ruler has been used, using a
            %default if not
            if  strcmp(app.ScaleSetLamp.Enable, 'on')
                scaleFactor = app.ScaleEditField.Value;
            else
                scaleFactor = 0.4/1080;
            end 
            
            Vmag = Vmag .* scaleFactor ./ dt;
            Vmag = Vmag .* imerode(app.frame(:,:,3) > 5,ones(3,3));

            switch app.ContrastenhanceCheckBox.Value
                case 0
                    imshow(app.frame,'Parent',app.ImageAxes);
                case 1
                    Mask = app.frame(:,:,3) > 5;
                    imshow(repmat(app.imgsub .* Mask,[1,1,3]),'Parent',app.ImageAxes);
            end
            hold(app.ImageAxes,'on')
            % Contour has temporary limits until default scale is set
            contourf(Vmag,linspace(0.1,app.VelocityupperlimitmsEditField.Value,16),'LineStyle','none','Parent',app.ImageAxes);
            colormap(app.ImageAxes, 'jet')
            hold(app.ImageAxes,'off')
        end
        
        function configure_slider(app)
            nstart = 0;
            nend = app.vid_obj.Duration/60;
            
            if nstart == nend
                nend = nend + 1;
            end
            
            app.Slider.Limits = [nstart, nend];
            app.Slider.Value = nstart;

            app.Slider.MajorTickLabels = mat2cell(num2str(linspace(nstart,nend,5)','%3.2f'),ones(5,1));
            app.Slider.MajorTicks = linspace(nstart,nend,5);        
            app.Slider.MinorTicks = linspace(nstart,nend,13);    
            
        end

        function export_video(app)

            [app.VideofilenameEditField.Value,folderName] = uiputfile([app.VideofilenameEditField.Value,'.mp4']);

            % Setting up video file in active directory to write to
            FrameRate = app.vid_obj.FrameRate/2;
            cd(folderName)
            vid = VideoWriter([app.VideofilenameEditField.Value],'MPEG-4');
            vid.FrameRate = FrameRate;

            % Returning focus to window so it doesn't go minimise
            figure(app.UIFigure)

            % Setting slider value to start time and disabling
            app.Slider.Value = app.StarttimeminEditField.Value;
            app.Slider.Enable = 'off';
            app.Slider_2.Enable = 'off';
            app.ExportVideoClipButton.Text = 'Saving Video...';
            % Playing clip and writing to video file
            % Can be done without playing clip for faster export
            open(vid);
            while hasFrame(app.vid_obj) && app.Slider.Value <= app.EndtimeminEditField.Value
                app.frame = readFrame(app.vid_obj);
                app.updateframe
                %if app.EstimateVelocityCheckBox.Value == 1
                    %app.PreviewCheckBox.Value = 0;
                   % app.estimate_velocity
                %end
                drawnow
                if  app.ColourbarCheckBox.Value == 1
                    frames = getframe(app.ImageAxes,[0 -5 1000 540]);
                else
                    frames = getframe(app.ImageAxes);
                end
                writeVideo(vid,frames)
                Slim = app.Slider_2.Limits;
                PC = (((app.vid_obj.CurrentTime/60) - Slim(1)) ./ range(Slim))*100;
                app.ExportVideoClipButton.Text = ['Saving Video...',newline,'Time: ',num2str(app.vid_obj.CurrentTime/60,'%3.2f'),newline,'Progress: ',num2str(PC,'%3.2f'),'%'];
                if(app.vid_obj.CurrentTime < app.vid_obj.Duration)
                    app.Slider.Value = app.vid_obj.CurrentTime/60;
                    if app.vid_obj.CurrentTime < app.EndtimeminEditField
                    app.Slider_2.Value = app.vid_obj.CurrentTime/60;
                    end
                end
            end
            close(vid)
            app.Slider.Enable = 'on';
            app.Slider_2.Enable = 'on';
            app.ExportVideoClipButton.Text = 'Export Video Clip...';
            % Posting success popup for video export
            uialert(app.UIFigure,'Video exported','Success','Icon','success')

        end

        function ruler(app)
            % Deleting any previous lines, if button has been pushed twice
            linedelete = findobj(app.ImageAxes,'Type','images.roi.Line'); delete(linedelete);
            
            % Creating line on image
            roi_line = images.roi.Line(app.ImageAxes,'Position',[200 200; 500 200], 'StripeColor','y');

            % Enabling scale box and button
            app.SetscaleButton.Enable = 'on';
            app.DistancemmEditField.Enable = 'on';
            app.DistancemmEditFieldLabel.Enable = 'on';

            % Disabling scale set lamp
            app.ScaleSetLamp.Enable = 'off';       
        end

        function set_scale(app)
            % Writing line position and scale number to scale field
            linelength_mm = app.DistancemmEditField.Value;
            linedata = findobj(app.ImageAxes,'Type','images.roi.Line');
            linelength_px = pdist(linedata.Position,'euclidean');
            rulerScale = (linelength_mm/1000)/linelength_px;
            app.ScaleEditField.Value = rulerScale;
            
            % Remove line roi
            delete(linedata);

            % Disabling scale box and button
            app.SetscaleButton.Enable = 'off';
            app.DistancemmEditField.Enable = 'off';
            app.DistancemmEditFieldLabel.Enable = 'off';

            % Enabling scale set lamp
            app.ScaleSetLamp.Enable = 'on'; 
        end

        function zoom(app)
            % Adjusting extents of 2nd slider and making it, zoom out and start/stop
            % icons visible
            app.Slider_2.Limits = [app.StarttimeminEditField.Value app.EndtimeminEditField.Value];
            app.Slider_2.Visible = 'on';
            app.ZoomoutontimelineButton.Visible = 'on';
            app.ZoomStartLamp.Visible = 'on';
            app.ZoomStopLamp.Visible = 'on';

            % Disabling zoom button, 1st slider and lamps
            app.ZoominontimelineButton.Enable = 'off';
            app.Slider.Enable = 'off';
            app.StartLamp.Enable = 'off';
            app.StopLamp.Enable = 'off';

            % Changing both slider values to start time
            app.Slider.Value = app.StarttimeminEditField.Value;
            app.Slider_2.Value = app.StarttimeminEditField.Value;
            
            % Changing 1st slider values when 2nd slider is moved is done
            % in callback
            
        end

        function zoom_out(app)
            % Turning off visibility of zoom slider etc
            app.Slider_2.Visible = 'off';
            app.ZoomoutontimelineButton.Visible = 'off';
            app.ZoomStartLamp.Visible = 'off';
            app.ZoomStopLamp.Visible = 'off';

            % Enabling zoom button, 1st slider and lamps
            app.ZoominontimelineButton.Enable = 'on';
            app.Slider.Enable = 'on';
            app.StartLamp.Enable = 'on';
            app.StopLamp.Enable = 'on';
            
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)

            if strcmp(app.PlayButton.Text,'Play')
                app.PlayButton.Text = 'Pause';
            else
                app.PlayButton.Text = 'Play';
            end

            while hasFrame(app.vid_obj) && strcmp(app.PlayButton.Text,'Pause')
                app.frame = readFrame(app.vid_obj);
                app.updateframe
                
                drawnow
                if(app.vid_obj.CurrentTime < app.vid_obj.Duration)
                    app.Slider.Value = app.vid_obj.CurrentTime/60;
                end
                if app.Slider.Value > app.StarttimeminEditField.Value && app.Slider.Value < app.EndtimeminEditField.Value
                    app.Slider_2.Value = app.vid_obj.CurrentTime/60;
                end
                if strcmp(app.ZoomoutontimelineButton.Visible,'on') && app.Slider.Value >= app.EndtimeminEditField.Value
                     app.PlayButton.Text = 'Play';
                end
            end 
        end

        % Button pushed function: OpenFileButton
        function OpenFileButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.MOV;*.MP4;*.mov;*.mp4',...
                            'Video Files (*.MP4,*.MOV)';
                            '*.MOV','Apple Movie files (*.MOV)'; ...
                            '*.MP4','h.264 (*.MP4)'; ...
                            '*.*',  'All Files (*.*)'};
                        
            [f, p] = uigetfile(filterspec,'Select a Video File');
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               app.fname = [p f];
               read_vid(app);
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            updateframe(app)
            %if app.EstimateVelocityCheckBox.Value == 1
            %    app.estimate_velocity;
            %end
        end

        % Value changed function: ContrastenhanceCheckBox
        function ContrastenhanceCheckBoxValueChanged(app, event)
            updateframe(app)
%             switch event.Value
%                 case 0
%                     app.ColormapDropDown.Visible = 'off';
%                 case 1
%                     app.ColormapDropDown.Visible = 'on';
%             end
            
        end

        % Button pushed function: SetEndButton, SetStartButton
        function SetStartEndButtonPushed(app, event)
             % Moving and making visable start UI element
             slider_extent = app.Slider.Limits(1,2);
             slider_width = app.Slider.Position(1,3);

             % Adjusting start of zoom view
             
             switch event.Source.Text
                 case 'Set Start'
                    app.StartLamp.Position = [(217+(slider_width*(app.Slider.Value/slider_extent))) 97 20 19];
                    app.StartLamp.Visible = 'on';
                    app.StarttimeminEditField.Value = app.Slider.Value;
                 case 'Set End'
                    app.StopLamp.Position = [(217+(slider_width*(app.Slider.Value/slider_extent))) 97 20 19];
                    app.StopLamp.Visible = 'on'; 
                    app.EndtimeminEditField.Value = app.Slider.Value;
             end

            try
                app.Slider_2.Limits = [app.StarttimeminEditField.Value app.EndtimeminEditField.Value];
            catch
                ResetButtonPushed(app)
            end
        end

        % Value changed function: EndtimeminEditField
        function EndtimeminEditFieldValueChanged(app, event)
            configure_slider(app)
        end

        % Value changed function: StarttimeminEditField
        function StarttimeminEditFieldValueChanged(app, event)
            configure_slider(app)
        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            app.updateframe
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.StarttimeminEditField.Value = 0;
            app.EndtimeminEditField.Value = app.vid_obj.Duration/60;
            app.configure_slider

            % Making start and end icons invisible
            app.StartLamp.Visible = 'off';
            app.StopLamp.Visible = 'off';
        end

        % Button pushed function: RulerButton
        function RulerButtonPushed(app, event)
            app.ruler

        end

        % Button pushed function: SetscaleButton
        function SetscaleButtonPushed(app, event)
            app.set_scale

        end

        % Button pushed function: ExportVideoClipButton
        function ExportVideoClipButtonPushed(app, event)
             app.export_video

        end

        % Button pushed function: ZoominontimelineButton
        function ZoominontimelineButtonPushed(app, event)
            app.zoom

        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)

            updateframe(app)
            app.Slider.Value = app.Slider_2.Value;
        end

        % Button pushed function: ZoomoutontimelineButton
        function ZoomoutontimelineButtonPushed(app, event)
            app.zoom_out
        end

        % Callback function
        function ColourbarButtonValueChanged(app, event)

            colorbar( app.ImageAxes )
        end

        % Value changed function: ColourbarCheckBox
        function ColourbarCheckBoxValueChanged(app, event)
 
            switch event.Value
                case 1
                    colorbar( app.ImageAxes );
                case 0
                    colorbar( app.ImageAxes, 'off' );
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StarttimeminEditFieldLabel = uilabel(app.UIFigure);
            app.StarttimeminEditFieldLabel.Text = 'Start time (min)';
            app.StarttimeminEditField = uieditfield(app.UIFigure, 'numeric');
            app.StarttimeminEditField.ValueDisplayFormat = '%3.2g';
            app.StarttimeminEditField.ValueChangedFcn = createCallbackFcn(app, @StarttimeminEditFieldValueChanged, true);
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 1000];
            app.Slider.MajorTicks = [1 1000];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [1 101 201 301 401 501 601 701 801 901];
            app.Slider.Visible = 'off';
            app.Slider.Value = 1;
            app.SetStartButton = uibutton(app.UIFigure, 'push');
            app.SetStartButton.ButtonPushedFcn = createCallbackFcn(app, @SetStartEndButtonPushed, true);
            app.SetStartButton.Visible = 'off';
            app.SetStartButton.Text = 'Set Start';
            app.SetEndButton = uibutton(app.UIFigure, 'push');
            app.SetEndButton.ButtonPushedFcn = createCallbackFcn(app, @SetStartEndButtonPushed, true);
            app.SetEndButton.Visible = 'off';
            app.SetEndButton.Text = 'Set End';
            app.OpenFileButton = uibutton(app.UIFigure, 'push');
            app.OpenFileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFileButton.Text = 'Open File...';
            app.EndtimeminEditFieldLabel = uilabel(app.UIFigure);
            app.EndtimeminEditFieldLabel.Text = 'End time (min)';
            app.EndtimeminEditField = uieditfield(app.UIFigure, 'numeric');
            app.EndtimeminEditField.ValueDisplayFormat = '%3.2g';
            app.EndtimeminEditField.ValueChangedFcn = createCallbackFcn(app, @EndtimeminEditFieldValueChanged, true);
            app.fname_label = uilabel(app.UIFigure);
            app.fname_label.Text = '';
            app.ContrastenhanceCheckBox = uicheckbox(app.UIFigure);
            app.ContrastenhanceCheckBox.ValueChangedFcn = createCallbackFcn(app, @ContrastenhanceCheckBoxValueChanged, true);
            app.ContrastenhanceCheckBox.Text = 'Contrast enhance';
            app.EstimateVelocityCheckBox = uicheckbox(app.UIFigure);
            app.EstimateVelocityCheckBox.Text = 'Estimate Velocity';
            app.SetscaleButton = uibutton(app.UIFigure, 'push');
            app.SetscaleButton.ButtonPushedFcn = createCallbackFcn(app, @SetscaleButtonPushed, true);
            app.SetscaleButton.Enable = 'off';
            app.SetscaleButton.Text = 'Set scale';
            app.DistancemmEditFieldLabel = uilabel(app.UIFigure);
            app.DistancemmEditFieldLabel.Enable = 'off';
            app.DistancemmEditFieldLabel.Text = 'Distance (mm)';
            app.DistancemmEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancemmEditField.Enable = 'off';
            app.RulerButton = uibutton(app.UIFigure, 'push');
            app.RulerButton.ButtonPushedFcn = createCallbackFcn(app, @RulerButtonPushed, true);
            app.RulerButton.Text = 'Ruler';
            app.ColormapDropDown = uidropdown(app.UIFigure);
            app.ColormapDropDown.Items = {'gray', 'jet', 'hot', 'winter'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'gray';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.ScaleSetLampLabel = uilabel(app.UIFigure);
            app.ScaleSetLampLabel.Text = 'Scale Set';
            app.ScaleSetLamp = uilamp(app.UIFigure);
            app.ScaleSetLamp.Enable = 'off';
            app.StartLamp = uilamp(app.UIFigure);
            app.StartLamp.Visible = 'off';
            app.StopLamp = uilamp(app.UIFigure);
            app.StopLamp.Visible = 'off';
            app.ExportVideoClipButton = uibutton(app.UIFigure, 'push');
            app.ExportVideoClipButton.ButtonPushedFcn = createCallbackFcn(app, @ExportVideoClipButtonPushed, true);
            app.ExportVideoClipButton.Text = 'Export Video Clip...';
            app.VideofilenameEditFieldLabel = uilabel(app.UIFigure);
            app.VideofilenameEditFieldLabel.Text = 'Video file name';
            app.VideofilenameEditField = uieditfield(app.UIFigure, 'text');
            app.VideofilenameEditField.Placeholder = 'MyVideoName';
            app.VideofilenameEditField.Value = 'MyVideoName';
            app.ZoominontimelineButton = uibutton(app.UIFigure, 'push');
            app.ZoominontimelineButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominontimelineButtonPushed, true);
            app.ZoominontimelineButton.Visible = 'off';
            app.ZoominontimelineButton.Text = 'Zoom in on timeline';
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 1000];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Visible = 'off';
            app.ZoomStartLamp = uilamp(app.UIFigure);
            app.ZoomStartLamp.Visible = 'off';
            app.ZoomStopLamp = uilamp(app.UIFigure);
            app.ZoomStopLamp.Visible = 'off';
            app.ZoomoutontimelineButton = uibutton(app.UIFigure, 'push');
            app.ZoomoutontimelineButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomoutontimelineButtonPushed, true);
            app.ZoomoutontimelineButton.Visible = 'off';
            app.ZoomoutontimelineButton.Text = 'Zoom out on timeline';
            app.ColourbarCheckBox = uicheckbox(app.UIFigure);
            app.ColourbarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColourbarCheckBoxValueChanged, true);
            app.ColourbarCheckBox.Text = 'Colourbar';
            app.ScaleEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleEditFieldLabel.Text = 'Scale';
            app.ScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleEditField.Editable = 'off';
            app.ScaleEditField.Enable = 'off';
            app.ScaleEditField.Value = 0.00037;
            app.VelocityupperlimitmsEditFieldLabel = uilabel(app.UIFigure);
            app.VelocityupperlimitmsEditFieldLabel.Text = 'Velocity upper limit (m/s)';
            app.VelocityupperlimitmsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VelocityupperlimitmsEditField.Value = 1;
            app.FalsecolourCheckBox = uicheckbox(app.UIFigure);
            app.FalsecolourCheckBox.Text = 'False colour';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PORSAViz_v0p6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end